classdef GestoreAnalisiPN < matlab.apps.AppBase
    %GESTOREANALISIPN L'applicazione permette di gestire facilmente le
    %transizioni e i posti del sistema, identificandone suddivisione in
    %macchinari, tipologia (temporizzate/immediate), probabilità e rates.
    %   L'applicazione richiede in ingresso un'array di stringhe dei nomi
    %   delle transizioni, e uno dei nomi dei posti.
    %   La prima schermata mostra i nomi dei macchinari con i quali i posti
    %   e le transizioni sono raggruppabili. Ogni macchinario è affiancato
    %   da una gruppo di bottoni per selezionare la sua analisi.
    %   La seconda schermata mostra le transizioni presenti, affiancata da
    %   bottoni per selezionare la tipologia (immediata/temporizzata), la
    %   probabilità qualora siano in conflitto più transizioni. Se la
    %   transizione è temporizzata si può specificare anche il rate. Le
    %   transizioni, cosi come i posti nell'ultima schermata, possono
    %   essere raggrupati selezionando il macchinario di appartenenza. 
    %   Cliccando il bottone 'SALVA' viene salvato il file sistema.mat
    %   contenente tutte le informazioni inserite nell'applicazione
    %   relative al sistema. 
    %   Se al momento dell'apertura dell'applicazione il file sistema.mat è
    %   già presente, i dati presenti all'interno sono verificati per
    %   rappresentare sull'interfaccia e preservare i dati preesistenti.
    %   
    %   Input
    %      - Transizioni: array di stringhe
    %      - Posti: array di stringhe
    %   
    %   
    %   Autori
    %      - Caponi Luca
    %      - Catalini Federico
    %      - Di Biase Alessandro

    % Properties that correspond to app components
    properties (Access = public)
        Figura              matlab.ui.Figure
        GridLayout2         matlab.ui.container.GridLayout
        SALVAButton         matlab.ui.control.Button
        TabGroup            matlab.ui.container.TabGroup
        MacchinariTab       matlab.ui.container.Tab
        GridLayout3         matlab.ui.container.GridLayout
        GridLayout5         matlab.ui.container.GridLayout
        MACCHINARIOLabel_3  matlab.ui.control.Label
        DAANALIZZARELabel   matlab.ui.control.Label
        GrigliaMacchinari   matlab.ui.container.GridLayout
        TransizioniTab      matlab.ui.container.Tab
        GridLayout6         matlab.ui.container.GridLayout
        GridLayout7         matlab.ui.container.GridLayout
        SERVERLabel         matlab.ui.control.Label
        MACCHINARIOLabel_4  matlab.ui.control.Label
        RATELabel           matlab.ui.control.Label
        PESOLabel           matlab.ui.control.Label
        TIPOLabel           matlab.ui.control.Label
        TRANSIZIONELabel    matlab.ui.control.Label
        GrigliaTransizioni  matlab.ui.container.GridLayout
        PostiTab            matlab.ui.container.Tab
        GridLayout8         matlab.ui.container.GridLayout
        GridLayout9         matlab.ui.container.GridLayout
        MACCHINARIOLabel_5  matlab.ui.control.Label
        POSTOLabel          matlab.ui.control.Label
        GrigliaPosti        matlab.ui.container.GridLayout
    end

    
    properties (Access = private)
        pathToMLAPP = fileparts(mfilename('fullpath'));

        Transizioni 
        Posti
        Macchinari

        % Tab macchinari
        Aggiungi                    matlab.ui.control.Button
        NomeMacchinario             matlab.ui.control.EditField
        DaAnalizzare                matlab.ui.container.ButtonGroup
        AnalizzareSi                matlab.ui.control.RadioButton
        AnalizzareNo                matlab.ui.control.RadioButton
        Rimuovi                     matlab.ui.control.Button

        % Tab transizioni
        Transizioni_nomi            matlab.ui.control.EditField
        Scelta_tipo                 matlab.ui.container.ButtonGroup
        Temporizzata                matlab.ui.control.RadioButton
        Immediata                   matlab.ui.control.RadioButton
        Transizioni_Peso            matlab.ui.control.NumericEditField
        Transizioni_Rate            matlab.ui.control.NumericEditField
        Transizioni_Macc            matlab.ui.control.DropDown
        
        Scelta_server               matlab.ui.container.ButtonGroup
        single_server               matlab.ui.control.RadioButton
        multiple_server             matlab.ui.control.RadioButton
        infinite_server             matlab.ui.control.RadioButton
        transizioni_n_server        matlab.ui.control.NumericEditField

        colore_Immediata    = [1.0 1.0 0.3];
        colore_Temporizzata = [0.5 0.5 1.0];
        colore_disabilitata = [0.9 0.9 0.9];
        colore_abilitata    = [1.0 1.0 1.0];
        colore_AnalizzareSi = [0.3 1.0 0.3];
        colore_AnalizzareNo = [1.0 0.7 0.3];
        colore_single= [1.0 0.4 0.2];
        colore_multi= [1.0 0.1 0.5];
        colore_inf= [1.0 0.2 0.6];

        % Tab posti
        Posti_nomi                  matlab.ui.control.EditField
        Posti_Macc                  matlab.ui.control.DropDown

        Salva                       matlab.ui.control.Button
    end
    
    methods (Access = private)

        function NomeMacchinarioValueChanged(app, event)
            id = str2num(event.Source.Tag);
            ok=true;
            for i=1:length(app.NomeMacchinario)
                if id~=i && string(app.NomeMacchinario(id).Value)==string(app.NomeMacchinario(i).Value)
                    ok=false;
                    break;
                end
            end
            if ok
                lista_vecchia=app.Macchinari;
                app.Macchinari(id)=app.NomeMacchinario(id).Value;
                CorreggiMacchinariScelti(app,lista_vecchia);
            else
                app.NomeMacchinario(id).Value=app.Macchinari(id);
            end
        end

        function CorreggiMacchinariScelti(app,lista_vecchia)
            for i=1:length(app.Transizioni_Macc)
                prima=find(["",lista_vecchia]==string(app.Transizioni_Macc(i).Value));
                app.Transizioni_Macc(i).Items=["",app.Macchinari];
                if prima>1
                    app.Transizioni_Macc(i).Value=app.Macchinari(prima-1);
                else
                    app.Transizioni_Macc(i).BackgroundColor=app.colore_disabilitata;
                end
            end
            for i=1:length(app.Posti_Macc)
                prima=find(["",lista_vecchia]==string(app.Posti_Macc(i).Value));
                app.Posti_Macc(i).Items=["",app.Macchinari];
                if prima>1
                    app.Posti_Macc(i).Value=app.Macchinari(prima-1);
                else
                    app.Posti_Macc(i).BackgroundColor=app.colore_disabilitata;
                end
            end 
        end

        function DaAnalizzareSelectionChanged(app, event)
            id=str2num(event.Source.Tag);
            if app.DaAnalizzare(id).SelectedObject.Text=="Si"
                colore = app.colore_AnalizzareSi;
            else
                colore = app.colore_AnalizzareNo;
            end
            app.DaAnalizzare(id).BackgroundColor=colore;
            for i=1:length(app.Transizioni)
                if app.Transizioni_Macc(i).Value==app.Macchinari(id) 
                   app.Transizioni_Macc(i).BackgroundColor=colore; 
                end
            end
            for i=1:length(app.Posti)
                if app.Posti_Macc(i).Value==app.Macchinari(id) 
                   app.Posti_Macc(i).BackgroundColor=colore; 
                end
            end
        end

        function AggiungiButtonPushed(app, ~)
            n = length(app.NomeMacchinario)+1;
            app.GrigliaMacchinari.RowHeight(n)={50};

            k=n;
            ok=true;
            while ok
                ok=false;
                nuovonome=['Macchinario',num2str(k)];
                for i=1:n-1
                    if string(nuovonome)==string(app.NomeMacchinario(i).Value)
                        ok=true;
                        break;
                    end
                end
                k=k+1;
            end
            
            % Create NomeMacchinario
            app.NomeMacchinario(n) = uieditfield(app.GrigliaMacchinari, 'text');
            app.NomeMacchinario(n).Layout.Row = n;
            app.NomeMacchinario(n).Layout.Column = 2;
            app.NomeMacchinario(n).Value = nuovonome;
            app.NomeMacchinario(n).Tag = num2str(n);
            app.NomeMacchinario(n).CharacterLimits = [1 Inf];
            app.NomeMacchinario(n).ValueChangedFcn = createCallbackFcn(app, @NomeMacchinarioValueChanged, true);

            % Create DaAnalizzare
            app.DaAnalizzare(n) = uibuttongroup(app.GrigliaMacchinari);
            app.DaAnalizzare(n).BackgroundColor = [1 1 1];
            app.DaAnalizzare(n).Layout.Row = n;
            app.DaAnalizzare(n).Layout.Column = 3;
            app.DaAnalizzare(n).SelectionChangedFcn = createCallbackFcn(app, @DaAnalizzareSelectionChanged, true);
            app.DaAnalizzare(n).BackgroundColor=app.colore_AnalizzareSi;
            app.DaAnalizzare(n).Tag=num2str(n);
            
            % Create AnalizzareSi
            app.AnalizzareSi = uiradiobutton(app.DaAnalizzare(n));
            app.AnalizzareSi.Text = 'Si';
            app.AnalizzareSi.Position = [10 25 100 22];
            app.AnalizzareSi.Value = true;

            % Create AnalizzareNo
            app.AnalizzareNo = uiradiobutton(app.DaAnalizzare(n));
            app.AnalizzareNo.Text = 'No';
            app.AnalizzareNo.Position = [10 2 100 22];

            app.Rimuovi(n) = uibutton(app.GrigliaMacchinari, 'push');
            app.Rimuovi(n).ButtonPushedFcn = createCallbackFcn(app, @RimuoviButtonPushed, true);
            app.Rimuovi(n).BackgroundColor = [0.6902 0.8392 1];
            app.Rimuovi(n).Layout.Row = n;
            app.Rimuovi(n).Layout.Column = 4;
            app.Rimuovi(n).Text = '-';
            app.Rimuovi(n).FontSize = 18;
            app.Rimuovi(n).Tag = num2str(n);

            app.GrigliaMacchinari.RowHeight(n+1)={50};
            app.Aggiungi.Layout.Row=n+1;
            
            app.Macchinari=[app.Macchinari,string(nuovonome)];
            CorreggiMacchinariScelti(app,app.Macchinari);
        end

        function RimuoviButtonPushed(app, event)
            id = str2num(event.Source.Tag);
            
            delete(app.NomeMacchinario(id));
            delete(app.DaAnalizzare(id));
            delete(app.Rimuovi(id));

            app.Macchinari=[app.Macchinari(1:id-1),app.Macchinari(id+1:end)];
            app.NomeMacchinario=[app.NomeMacchinario(1:id-1),app.NomeMacchinario(id+1:end)];
            app.DaAnalizzare=[app.DaAnalizzare(1:id-1),app.DaAnalizzare(id+1:end)];
            app.Rimuovi=[app.Rimuovi(1:id-1),app.Rimuovi(id+1:end)];
            
            for i=id:length(app.NomeMacchinario)
                app.NomeMacchinario(i).Layout.Row=i;
                app.NomeMacchinario(i).Tag=num2str(i);
                app.DaAnalizzare(i).Layout.Row=i;
                app.DaAnalizzare(i).Tag=num2str(i);
                app.Rimuovi(i).Layout.Row=i;
                app.Rimuovi(i).Tag=num2str(i);
            end
            app.Aggiungi.Layout.Row=length(app.NomeMacchinario)+1;
            app.GrigliaMacchinari.RowHeight=ones(1,length(app.NomeMacchinario)+1)*50;
            
            CorreggiMacchinariScelti(app,app.Macchinari)
        end

        function Salvataggio(app)
            sistema.Transizioni=app.Transizioni;
            sistema.Posti = app.Posti;
            sistema.Macchinari = table(); 
            sistema.Pesi=table();
            sistema.Temporizzate=table();

            for i=1:length(app.Macchinari)
                val=app.DaAnalizzare(i).SelectedObject.Text=="Si";
                sistema.Macchinari(i,:)=table(app.Macchinari(i),{[]},{[]},val,'VariableNames',["Macchinario","Transizioni","Posti","DaAnalizzare"]);
            end
            for i=1:length(app.Transizioni_nomi)
                % Nome della transizione
                nome = string(app.Transizioni_nomi(i).Value);
                % 1 o 0 per transizione immediata o temporizzata
                sistema.maschera(i)=app.Scelta_tipo(i).SelectedObject.Text=="Immediata";
                % Probabilità della transizione, salvata solo se maggiore
                % di 0
                prob=app.Transizioni_Peso(i).Value;
                if prob>0
                    sistema.Pesi(height(sistema.Pesi)+1,:)=table(string(nome),prob);
                end
                % Rate della transizione, salvata solo se è temporizzata
                rate=app.Transizioni_Rate(i).Value;
                n_server=app.transizioni_n_server(i).Value;
                if sistema.maschera(i)==0
                    sistema.Temporizzate(height(sistema.Temporizzate)+1,:)=table(string(nome),rate,n_server);
                    
                end
                % Inserimento nella tabella macchinario
                macc=string(app.Transizioni_Macc(i).Value);
                if ~isempty(app.Macchinari)
                    id=find(macc==app.Macchinari);
                    if ~isempty(id)
                        sistema.Macchinari.Transizioni(id)={[sistema.Macchinari.Transizioni{id},nome]};
                    end
                end
            end
            for i=1:length(app.Posti_nomi)
                % Nome del posto
                nome = string(app.Posti_nomi(i).Value);
                % Inserimento nella tabella macchinario
                macc=string(app.Posti_Macc(i).Value);
                if ~isempty(app.Macchinari)
                    id=find(macc==app.Macchinari);
                    if ~isempty(id)
                        sistema.Macchinari.Posti(id)={[sistema.Macchinari.Posti{id},nome]};
                    end
                end
            end
            if height(sistema.Pesi)>=1
                sistema.Pesi.Properties.VariableNames = ["Transizione" "Pesi"];
            end
            if height(sistema.Temporizzate)>=1
                sistema.Temporizzate.Properties.VariableNames = ["Transizione" "Rate" "N_Server"];
            end
            save("sistema.mat","sistema");
        end
        
        function Gestionetipo(app,immediata,id)
            if immediata
                app.Transizioni_Rate(id).Editable='off';
                app.Transizioni_Rate(id).BackgroundColor=app.colore_disabilitata;
                app.Scelta_tipo(id).BackgroundColor=app.colore_Immediata; 
                app.single_server(id).Enable='off';
                app.multiple_server(id).Enable='off';
                app.infinite_server(id).Enable='off';
                app.transizioni_n_server(id).Editable='off';
                app.transizioni_n_server(id).BackgroundColor=app.colore_disabilitata;
            else
                app.Transizioni_Rate(id).Editable='on';
                app.Transizioni_Rate(id).BackgroundColor=app.colore_abilitata;
                app.Scelta_tipo(id).BackgroundColor=app.colore_Temporizzata;
                app.single_server(id).Enable='on';
                app.multiple_server(id).Enable='on';
                app.infinite_server(id).Enable='on';
                app.transizioni_n_server(id).Editable="off";
                app.transizioni_n_server(id).BackgroundColor=app.colore_disabilitata;
                if app.multiple_server(id).Value==1
                    app.transizioni_n_server(id).Editable='on';
                    app.transizioni_n_server(id).BackgroundColor=app.colore_abilitata;
                end
            end
        end

        function CorreggiNumeroServer(app,event)
            id = str2num(event.Source.Tag);
            n_server = app.transizioni_n_server(id).Value;
            if n_server==1
               % Allora deve essere single server 
               app.single_server(id).Value=1;
               app.transizioni_n_server(id).Editable='off';
               app.transizioni_n_server(id).BackgroundColor=app.colore_disabilitata;
            elseif n_server==Inf
               % Allora deve essere infinite server 
               app.infinite_server(id).Value=Inf;
               app.transizioni_n_server(id).Editable='off';
               app.transizioni_n_server(id).BackgroundColor=app.colore_disabilitata;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Transizioni, Posti)
            
            app.Transizioni=Transizioni;
            app.Posti=Posti;
            
            if exist("sistema.mat","file")
                file=load("sistema.mat",'sistema');
                try
                    Pesi = file.sistema.Pesi;
                catch
                    Pesi = table("",0,'VariableNames',["Transizione" "Pesi"]);
                end
                try
                    Temporizzate = file.sistema.Temporizzate;
                catch
                    Temporizzate = table("",0,0,'VariableNames',["Transizione" "Rate" "N_Server"]);
                end
                try
                    Transizioni_maschera = file.sistema.Transizioni;
                    maschera = file.sistema.maschera;
                catch
                    Transizioni_maschera = app.Transizioni;
                    maschera = ones(size(app.Transizioni));
                end
                try
                    Macchinari_ = file.sistema.Macchinari.Macchinario';
                    Tabella_Macchinari = file.sistema.Macchinari;
                    if ~ismember("DaAnalizzare",Tabella_Macchinari.Properties.VariableNames)
                        Tabella_Macchinari.DaAnalizzare=ones(height(Tabella_Macchinari),1);
                    end
                catch
                    Macchinari_=[];
                    Tabella_Macchinari = table();
                end
            else
                Macchinari_=[];
                Tabella_Macchinari = table("",{[]},{[]},1,'VariableNames',["Macchinario","Transizioni","Posti","DaAnalizzare"]);
                Pesi = table("",0,'VariableNames',["Transizione" "Pesi"]);
                Temporizzate = table("",0,0,'VariableNames',["Transizione" "Rate" "N_Server"]);
                Transizioni_maschera = app.Transizioni;
                maschera = ones(size(app.Transizioni));
            end
            app.Macchinari=Macchinari_;

%% MACCHINARI =============================================================
            for i=1:length(app.Macchinari)
            
                app.GrigliaMacchinari.RowHeight(i)={50};

                % Create NomeMacchinario
                app.NomeMacchinario(i) = uieditfield(app.GrigliaMacchinari, 'text');
                app.NomeMacchinario(i).Layout.Row = i;
                app.NomeMacchinario(i).Layout.Column = 2;
                app.NomeMacchinario(i).Value = app.Macchinari(i);
                app.NomeMacchinario(i).Tag = num2str(i);
                app.NomeMacchinario(i).CharacterLimits = [1 Inf];
                app.NomeMacchinario(i).ValueChangedFcn = createCallbackFcn(app, @NomeMacchinarioValueChanged, true);

                % Create DaAnalizzare
                app.DaAnalizzare(i) = uibuttongroup(app.GrigliaMacchinari);
                app.DaAnalizzare(i).BackgroundColor = [1 1 1];
                app.DaAnalizzare(i).Layout.Row = i;
                app.DaAnalizzare(i).Layout.Column = 3;
                app.DaAnalizzare(i).SelectionChangedFcn = createCallbackFcn(app, @DaAnalizzareSelectionChanged, true);
                app.DaAnalizzare(i).Tag = num2str(i);

                % Create AnalizzareSi
                app.AnalizzareSi = uiradiobutton(app.DaAnalizzare(i));
                app.AnalizzareSi.Text = 'Si';
                app.AnalizzareSi.Position = [10 25 100 22];
            
                % Create AnalizzareNo
                app.AnalizzareNo = uiradiobutton(app.DaAnalizzare(i));
                app.AnalizzareNo.Text = 'No';
                app.AnalizzareNo.Position = [10 2 100 22];
                
                if Tabella_Macchinari.DaAnalizzare(i)
                    app.AnalizzareSi.Value=true;
                    app.DaAnalizzare(i).BackgroundColor=app.colore_AnalizzareSi;
                else
                    app.AnalizzareSi.Value=false;
                    app.DaAnalizzare(i).BackgroundColor=app.colore_AnalizzareNo;
                end

                % Create Rimuovi
                app.Rimuovi(i) = uibutton(app.GrigliaMacchinari, 'push');
                app.Rimuovi(i).ButtonPushedFcn = createCallbackFcn(app, @RimuoviButtonPushed, true);
                app.Rimuovi(i).BackgroundColor = [0.6902 0.8392 1];
                app.Rimuovi(i).Layout.Row = i;
                app.Rimuovi(i).Layout.Column = 4;
                app.Rimuovi(i).Text = '-';
                app.Rimuovi(i).FontSize = 18;
                app.Rimuovi(i).Tag = num2str(i);
            end

            % Create Aggiungi
            app.Aggiungi = uibutton(app.GrigliaMacchinari, 'push');
            app.Aggiungi.ButtonPushedFcn = createCallbackFcn(app, @AggiungiButtonPushed, true);
            app.Aggiungi.BackgroundColor = [0.6902 0.8392 1];
            app.GrigliaMacchinari.RowHeight(length(app.NomeMacchinario)+1)={50};
            app.Aggiungi.Layout.Row = length(app.NomeMacchinario)+1;
            app.Aggiungi.Layout.Column = 1;
            app.Aggiungi.Text = '+';
            app.Aggiungi.FontSize = 18;

%% TRANSIZIONI ============================================================
            for i=1:length(Transizioni)
                app.GrigliaTransizioni.RowHeight(i)={50};
                app.Transizioni_nomi(i) = uieditfield(app.GrigliaTransizioni, 'text');
                app.Transizioni_nomi(i).Value = Transizioni(i);
                app.Transizioni_nomi(i).Editable = 'off';
                app.Transizioni_nomi(i).Layout.Row = i;
                app.Transizioni_nomi(i).Layout.Column = 1;

                % Create ButtonGroup
                app.Scelta_tipo(i) = uibuttongroup(app.GrigliaTransizioni);
                app.Scelta_tipo(i).Tag = num2str(i);
                app.Scelta_tipo(i).SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            
                % Create ImmediataButton
                app.Immediata = uiradiobutton(app.Scelta_tipo(i));
                app.Immediata.Text = 'Immediata';
                app.Immediata.Position = [11 24 100 22];

                % Create TemporizzataButton
                app.Temporizzata = uiradiobutton(app.Scelta_tipo(i));
                app.Temporizzata.Text = 'Temporizzata';
                app.Temporizzata.Position = [11 2 100 22];
                
                app.Transizioni_Peso(i) = uieditfield(app.GrigliaTransizioni, 'numeric');
                app.Transizioni_Peso(i).Layout.Row = i;
                app.Transizioni_Peso(i).Layout.Column = 3;
                if ~isempty(Pesi) && ismember(app.Transizioni(i),Pesi.Transizione)
                    app.Transizioni_Peso(i).Value=Pesi.Pesi(Pesi.Transizione==app.Transizioni(i));
                end

                app.Transizioni_Rate(i) = uieditfield(app.GrigliaTransizioni, 'numeric');
                app.Transizioni_Rate(i).Layout.Row = i;
                app.Transizioni_Rate(i).Layout.Column = 4;
                app.Transizioni_Rate(i).Limits=[0 inf];
                app.Transizioni_Rate(i).LowerLimitInclusive = 'off';

                if ~isempty(Temporizzate) && ismember(app.Transizioni(i),Temporizzate.Transizione)
                    app.Transizioni_Rate(i).Value=Temporizzate.Rate(Temporizzate.Transizione==app.Transizioni(i));
                end
   
                if ismember(app.Transizioni(i),Transizioni_maschera)
                    app.Immediata.Value = logical(maschera(Transizioni_maschera==app.Transizioni(i)));
                else
                    app.Immediata.Value = true;
                end
                
                % Create ButtonGroup
                app.Scelta_server(i) = uibuttongroup(app.GrigliaTransizioni);
                app.Scelta_server(i).Tag = num2str(i);
                app.Scelta_server(i).SelectionChangedFcn = createCallbackFcn(app, @ServerGroupSelectionChanged, true);
                app.Scelta_server(i).Layout.Row = i;
                app.Scelta_server(i).Layout.Column = 5;
            
                % Create Single_serverButton
                app.single_server(i) = uiradiobutton(app.Scelta_server(i));
                app.single_server(i).Text = 'Single server';
                app.single_server(i).Position = [11 34 100 16];

                % Create Multiple_serverButton
                app.multiple_server(i) = uiradiobutton(app.Scelta_server(i));
                app.multiple_server(i).Text = 'Multiple server';
                app.multiple_server(i).Position = [11 18 100 16];

                % Create Infinite_serverButton
                app.infinite_server(i) = uiradiobutton(app.Scelta_server(i));
                app.infinite_server(i).Text = 'Infinite server';
                app.infinite_server(i).Position = [11 2 100 16];

                %Create numero_server
                app.transizioni_n_server(i) = uieditfield(app.GrigliaTransizioni, 'numeric');
                app.transizioni_n_server(i).Layout.Row = i;
                app.transizioni_n_server(i).Layout.Column = 6;
                app.transizioni_n_server(i).Limits=[1 inf];
                app.transizioni_n_server(i).LowerLimitInclusive = 'on';
                app.transizioni_n_server(i).Tag=num2str(i);
                app.transizioni_n_server(i).ValueChangedFcn = createCallbackFcn(app, @CorreggiNumeroServer, true);

                if ~isempty(Temporizzate) && ismember(app.Transizioni(i),Temporizzate.Transizione)
                    app.transizioni_n_server(i).Value=Temporizzate.N_Server(Temporizzate.Transizione==app.Transizioni(i));
                    if app.transizioni_n_server(i).Value==1
                        app.single_server(i).Value=1;
                    elseif app.transizioni_n_server(i).Value==Inf
                        app.infinite_server(i).Value=1;
                    else
                        app.multiple_server(i).Value=1;
                    end
                end

                app.Transizioni_Macc(i) = uidropdown(app.GrigliaTransizioni);
                app.Transizioni_Macc(i).ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
                app.Transizioni_Macc(i).Layout.Row = i;
                app.Transizioni_Macc(i).Layout.Column = 7;
                app.Transizioni_Macc(i).Items = ["",app.Macchinari];
                app.Transizioni_Macc(i).Tag = string(['T',num2str(i)]);
                app.Transizioni_Macc(i).BackgroundColor=app.colore_disabilitata;
                for j=1:height(Tabella_Macchinari)
                    if ~isempty(Tabella_Macchinari.Transizioni{j}) && ismember(app.Transizioni(i),Tabella_Macchinari.Transizioni{j})
                        app.Transizioni_Macc(i).Value=Tabella_Macchinari.Macchinario(j);
                        if Tabella_Macchinari.DaAnalizzare(j)
                            app.Transizioni_Macc(i).BackgroundColor = app.colore_AnalizzareSi;
                        else
                            app.Transizioni_Macc(i).BackgroundColor = app.colore_AnalizzareNo;
                        end
                        break;
                    end
                end
               Gestionetipo(app, app.Immediata.Value, i); 
            end


%% POSTI ==================================================================
            for i=1:length(Posti)
                app.GrigliaPosti.RowHeight(i) = {50};
                app.Posti_nomi(i) = uieditfield(app.GrigliaPosti, 'text');
                app.Posti_nomi(i).Value = Posti(i);
                app.Posti_nomi(i).Editable = 'off';
                app.Posti_nomi(i).Layout.Row = i;
                app.Posti_nomi(i).Layout.Column = 1;

                app.Posti_Macc(i) = uidropdown(app.GrigliaPosti);
                app.Posti_Macc(i).ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
                app.Posti_Macc(i).Layout.Row = i;
                app.Posti_Macc(i).Layout.Column = 2;
                app.Posti_Macc(i).Items = ["",app.Macchinari];
                app.Posti_Macc(i).BackgroundColor=[1 1 1];
                app.Posti_Macc(i).Tag = string(['P',num2str(i)]);
                app.Posti_Macc(i).BackgroundColor=app.colore_disabilitata;
                for j=1:height(Tabella_Macchinari)
                    if ~isempty(Tabella_Macchinari.Posti{j}) && ismember(app.Posti(i),Tabella_Macchinari.Posti{j})
                        app.Posti_Macc(i).Value=Tabella_Macchinari.Macchinario(j);
                        if Tabella_Macchinari.DaAnalizzare(j)
                            app.Posti_Macc(i).BackgroundColor = app.colore_AnalizzareSi;
                        else
                            app.Posti_Macc(i).BackgroundColor = app.colore_AnalizzareNo;
                        end
                        break;
                    end
                end
            end
        end

        % Callback function
        function ButtonGroupSelectionChanged(app, event)
            id = str2num(event.Source.Tag);
            selectedButton = app.Scelta_tipo(id).SelectedObject.Text;
            Gestionetipo(app, selectedButton=="Immediata", id);
            
        end

        % Button pushed function: SALVAButton
        function SALVAButtonPushed(app, event)
            Salvataggio(app);
        end

        % Callback function
        function DropDownValueChanged(app, event)
            caratteri=char(event.Source.Tag);
            id=str2num(caratteri(2:end));
            if caratteri(1)=='T'
                value = app.Transizioni_Macc(id).Value;
                if value~=""
                    id_macc=find(value==app.Macchinari);
                    if app.DaAnalizzare(id_macc).SelectedObject.Text=="Si"
                        app.Transizioni_Macc(id).BackgroundColor = app.colore_AnalizzareSi;
                    else
                        app.Transizioni_Macc(id).BackgroundColor = app.colore_AnalizzareNo;
                    end
                else
                    app.Transizioni_Macc(id).BackgroundColor=app.colore_disabilitata;
                end
            elseif event.Source.Tag(1)=='P'
                value = app.Posti_Macc(id).Value;
                if value~=""
                    id_macc=find(value==app.Macchinari);
                    if app.DaAnalizzare(id_macc).SelectedObject.Text=="Si"
                        app.Posti_Macc(id).BackgroundColor = app.colore_AnalizzareSi;
                    else
                        app.Posti_Macc(id).BackgroundColor = app.colore_AnalizzareNo;
                    end
                else
                    app.Posti_Macc(id).BackgroundColor=app.colore_disabilitata;
                end
            end
        end

        % Close request function: Figura
        function ChiusuraApp(app, event)
            Salvataggio(app);
            delete(app)
        end

        % Callback function
        function ServerGroupSelectionChanged(app, event)
            position = app.TransizioniTab.Position;

          id = str2num(event.Source.Tag);
            selectedButton = app.Scelta_server(id).SelectedObject.Text;
            if selectedButton~="Multiple server"
                app.transizioni_n_server(id).Editable='off';
                app.transizioni_n_server(id).BackgroundColor=app.colore_disabilitata;
                %app.Scelta_server(id).BackgroundColor=app.colore_Immediata;
            else
                app.transizioni_n_server(id).Editable='on';
                app.transizioni_n_server(id).BackgroundColor=app.colore_abilitata;
                %app.numero_server(id).BackgroundColor=app.colore_Temporizzata;
            end  
            if selectedButton=="Single server"
                app.transizioni_n_server(id).Value=1;
            elseif selectedButton=="Infinite server"
                app.transizioni_n_server(id).Value=Inf;
            else
                app.transizioni_n_server(id).Value=2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Figura = uifigure('Visible', 'off');
            app.Figura.Name = 'GestoreAnalisiPN';
            app.Figura.CloseRequestFcn = createCallbackFcn(app, @ChiusuraApp, true);
            app.GridLayout2 = uigridlayout(app.Figura);
            app.TabGroup = uitabgroup(app.GridLayout2);
            app.MacchinariTab = uitab(app.TabGroup);
            app.MacchinariTab.Title = 'Macchinari';
            app.GridLayout3 = uigridlayout(app.MacchinariTab);
            app.GrigliaMacchinari = uigridlayout(app.GridLayout3);
            app.GridLayout5 = uigridlayout(app.GridLayout3);
            app.DAANALIZZARELabel = uilabel(app.GridLayout5);
            app.DAANALIZZARELabel.Text = 'DA ANALIZZARE';
            app.MACCHINARIOLabel_3 = uilabel(app.GridLayout5);
            app.MACCHINARIOLabel_3.Text = 'MACCHINARIO';
            app.TransizioniTab = uitab(app.TabGroup);
            app.TransizioniTab.Title = 'Transizioni';
            app.GridLayout6 = uigridlayout(app.TransizioniTab);
            app.GrigliaTransizioni = uigridlayout(app.GridLayout6);
            app.GridLayout7 = uigridlayout(app.GridLayout6);
            app.TRANSIZIONELabel = uilabel(app.GridLayout7);
            app.TRANSIZIONELabel.Text = 'TRANSIZIONE';
            app.TIPOLabel = uilabel(app.GridLayout7);
            app.TIPOLabel.Text = 'TIPO';
            app.PESOLabel = uilabel(app.GridLayout7);
            app.PESOLabel.Text = 'PESO';
            app.RATELabel = uilabel(app.GridLayout7);
            app.RATELabel.Text = 'RATE';
            app.MACCHINARIOLabel_4 = uilabel(app.GridLayout7);
            app.MACCHINARIOLabel_4.Text = 'MACCHINARIO';
            app.SERVERLabel = uilabel(app.GridLayout7);
            app.SERVERLabel.Text = 'SERVER';
            app.PostiTab = uitab(app.TabGroup);
            app.PostiTab.Title = 'Posti';
            app.GridLayout8 = uigridlayout(app.PostiTab);
            app.GrigliaPosti = uigridlayout(app.GridLayout8);
            app.GridLayout9 = uigridlayout(app.GridLayout8);
            app.POSTOLabel = uilabel(app.GridLayout9);
            app.POSTOLabel.Text = 'POSTO';
            app.MACCHINARIOLabel_5 = uilabel(app.GridLayout9);
            app.MACCHINARIOLabel_5.Text = 'MACCHINARIO';
            app.SALVAButton = uibutton(app.GridLayout2, 'push');
            app.SALVAButton.ButtonPushedFcn = createCallbackFcn(app, @SALVAButtonPushed, true);
            app.SALVAButton.Text = 'SALVA';
            % Show the figure after all components are created
            app.Figura.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GestoreAnalisiPN(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Figura)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Figura)
        end
    end
end